classdef MAIBayBayOCR_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MAIBaybayOCRUIFigure        matlab.ui.Figure
        GridLayout                  matlab.ui.container.GridLayout
        Button_4                    matlab.ui.control.Button
        Button_5                    matlab.ui.control.Button
        Button_3                    matlab.ui.control.Button
        TextArea_9                  matlab.ui.control.TextArea
        Panel_6                     matlab.ui.container.Panel
        TransliterationLabel        matlab.ui.control.Label
        SaveTransliterationsButton  matlab.ui.control.Button
        TextArea_7                  matlab.ui.control.TextArea
        Panel_5                     matlab.ui.container.Panel
        ConvertedImageLabel         matlab.ui.control.Label
        SaveImageButton             matlab.ui.control.Button
        Image2                      matlab.ui.control.Image
        Image_2                     matlab.ui.control.Image
        Panel_4                     matlab.ui.container.Panel
        CONVERTLabel_2              matlab.ui.control.Label
        TextArea_5                  matlab.ui.control.TextArea
        TransliterateButton         matlab.ui.control.Button
        SELECTOCROPTIONLabel        matlab.ui.control.Label
        INPUTTEXTIMAGELabel         matlab.ui.control.Label
        Panel_2                     matlab.ui.container.Panel
        UploadNewImageButton        matlab.ui.control.Button
        ClicktoUploadImageButton    matlab.ui.control.Button
        Image                       matlab.ui.control.Image
        Panel                       matlab.ui.container.Panel
        DetectionSummaryLabel_2     matlab.ui.control.Label
        TextArea_8                  matlab.ui.control.TextArea
        TextArea_6                  matlab.ui.control.TextArea
        Panel_3                     matlab.ui.container.Panel
        OCROptionsDropDown          matlab.ui.control.DropDown
        OCROptionsDropDownLabel     matlab.ui.control.Label
        TextArea_3                  matlab.ui.control.TextArea
        TextArea_4                  matlab.ui.control.TextArea
    end

    
    properties (Access = private)
        OCROpt % OCR Option
        InputImage  %Input image
        BaybayinMdl %Baybayin SVM Model
        LvsBMdl     %LvsB SVM Model
        Tag_Words   %Tagalog corpus
        Trans_Image %Save Image
        Out_Message %Save Transliterated Words
        Message1    %Detection summary
    end
    
    methods (Access = private)
        function [WBB,A,output,Baybayin_counter,RW]=BaybayinDetectStart(app,fullname,ocropt,lvsbmodel)
          [output1,WBB,A,output,Baybayin_counter,message1,RW]=BaybayinOCR(fullname,ocropt,lvsbmodel);
           app.Image.ImageSource=output1;
           app.TextArea_6.Visible='on';
           app.TextArea_6.Value=message1;
           app.Message1=message1;
           pause(0.1)
           close all;
        end
        
        function Pagbati(~,im)
           figure('Name','PAGBATI!','visible','on','NumberTitle','off');
           imshow(im);
        
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.MAIBaybayOCRUIFigure.Resize='on';
            movegui(app.MAIBaybayOCRUIFigure, 'center');
         %   app.MAIBaybayOCRUIFigure.Children.AutoResizeChildren='off';
            if isempty(gcp('nocreate'))
                parpool('processes');
            end
            %load your API key environment first example:
            %loadenv("APIkeyFileName.env")
            
            app.MAIBaybayOCRUIFigure.Units='normalized';
            app.Panel_2.Units='normalized'; app.Panel_5.Units='normalized';
            app.Panel.Units='normalized'; app.Panel_3.Units='normalized'; app.Panel_4.Units='normalized'; app.Panel_6.Units='normalized';
 

           app.TextArea_3.Visible='off'; 
           app.BaybayinMdl= load("Baybayin_Character_Classifier_00379.mat", "Baybayin_Character_Classifier_00379");      %load Baybayin character SVM Classifier
           app.LvsBMdl= load("LvsB_classifier_00125.mat", "LvsB_classifier_00125");                                      %load Latin vs Baybayin SVM Classifier
            filename='Tagalog_words_74419+.csv';                                                     %
            fileID=fopen(filename);                                                                  % Installing the Tagalog Word                           
            Tagalog_words= textscan(fileID,'%s'); Tagalog_words=Tagalog_words{1}; fclose(fileID);    % Database (Corpus)
           app.Tag_Words=Tagalog_words;                                                              %   
           app.OCROptionsDropDown.ItemsData=[1,2];
           app.OCROptionsDropDown.Value=1;
           app.OCROpt=app.OCROptionsDropDown.Value;
           app.TextArea_9.Value={'© R. Pino, R. Mendoza, R. Sambayan, University of the Philippines Diliman 2026'};
           app.Button_3.Text='© R. Pino, R. Mendoza, R. Sambayan, University of the Philippines Diliman 2026';
 
          im=imread('1_PAGBATI.png');
           Pagbati(app,im);
           pause(5);
        end

        % Value changed function: OCROptionsDropDown
        function OCROptionsDropDownValueChanged(app, event)

            app.TextArea_6.Value={''}; app.TextArea_5.Value={''}; app.TextArea_8.Visible='off';
%            app.TextArea_6.Visible='on';
            app.Image2.Visible='on';
            app.Image_2.Visible='off'; app.TextArea_7.Value={''}; app.SaveImageButton.Visible='off'; app.SaveTransliterationsButton.Visible='off';
            
            app.OCROptionsDropDown.ItemsData=[1,2];
            value = app.OCROptionsDropDown.Value;
            app.OCROpt=value;

            if ~isempty(app.Image.ImageSource)
                if isempty(app.InputImage)
                    return;
                end
            app.Image.ImageSource=app.InputImage;
            end            
            
            if app.OCROpt==2
                app.TextArea_4.Visible='off';
                app.TextArea_3.Visible='on';
            elseif app.OCROpt==1
                app.TextArea_4.Visible='on';
                app.TextArea_3.Visible='off'; 
            end

        end

        % Button pushed function: ClicktoUploadImageButton
        function ClicktoUploadImageButtonPushed(app, event)

            app.TextArea_6.Visible='on';
           [file1,path] = uigetfile({'*.png;*.jpg;*.jpeg','Supported Files (*.png,*.jpg,*.jpeg)';'*.png','Portable Network Graphic file (*.png)';'*.jpg','JPG file (*.jpg)';'*.jpeg','JPEG file (*.jpeg)'}); figure(app.MAIBaybayOCRUIFigure);
           fullname = [path, file1];
 %          ImageFile = imread(fullname);
            if file1==0
               return; 
            end 
           app.InputImage=fullname; 
           app.Image.ImageSource=fullname;
            app.ClicktoUploadImageButton.Visible='off';
%            app.Image.Enable='on';
           app.UploadNewImageButton.Visible='on';
           app.UploadNewImageButton.Enable='on';
           
        end

        % Button pushed function: UploadNewImageButton
        function UploadNewImageButtonPushed(app, event)
           [file1,path] = uigetfile({'*.png;*.jpg;*.jpeg','Supported Files (*.png,*.jpg,*.jpeg)';'*.png','Portable Network Graphic file (*.png)';'*.jpg','JPG file (*.jpg)';'*.jpeg','JPEG file (*.jpeg)'}); figure(app.MAIBaybayOCRUIFigure);
            if file1==0
               return;
            else
            app.TextArea_6.Value={''}; app.TextArea_5.Value={''}; app.TextArea_8.Visible='off';
%            app.TextArea_6.Visible='on';
            app.Image2.Visible='on';
            app.Image_2.Visible='off'; app.TextArea_7.Value={''}; app.SaveImageButton.Visible='off'; app.SaveTransliterationsButton.Visible='off';
            end
           
           fullname = [path, file1];
 %          ImageFile = imread(fullname);
           app.InputImage=fullname;
           app.Image.ImageSource=fullname;
           app.UploadNewImageButton.Visible='on';
           app.UploadNewImageButton.Enable='on';
           app.Image_2.Visible='off';
        end

        % Button pushed function: TransliterateButton
        function TransliterateButtonPushed(app, event)
         try
          if ~isempty(findobj('Type', 'figure'))
               clf;
          end   
          app.ClicktoUploadImageButton.Enable='off'; app.OCROptionsDropDown.Enable='off'; app.TransliterateButton.Enable='off'; app.UploadNewImageButton.Enable='off';
          app.SaveImageButton.Enable='off'; app.SaveTransliterationsButton.Enable='off'; app.Button_3.Enable='off'; app.Button_4.Enable='off'; app.Button_5.Enable='off';
            
            
        app.TextArea_6.Value={''}; app.TextArea_5.Value={''} ;app.Image2.Visible='on'; app.Image_2.Visible='off'; app.TextArea_7.Value={''}; app.TextArea_6.FontColor=[0 0 0];
           app.TextArea_5.Visible='on'; %app.TextArea_8.Value={''};
           fullname=app.InputImage;
           if isempty(fullname) || isequal(0,fullname)
          app.ClicktoUploadImageButton.Enable='on'; app.OCROptionsDropDown.Enable='on'; app.TransliterateButton.Enable='on'; app.UploadNewImageButton.Enable='on';
          app.SaveImageButton.Enable='on'; app.SaveTransliterationsButton.Enable='on'; app.Button_3.Enable='on'; app.Button_4.Enable='on'; app.Button_5.Enable='on';               
               return;
           end           
           app.TextArea_5.Value='Processing...'; drawnow %pause(0.01)
           
           ocropt=app.OCROpt; lvsbmodel=app.LvsBMdl.LvsB_classifier_00125;
            [WBB,A,output,Baybayin_counter,RW]=BaybayinDetectStart(app,fullname,ocropt,lvsbmodel);

           
           if sum(output)~=length(output)
           [output2,transliterations,single_lines3]=Baybayintransliterations(app.BaybayinMdl.Baybayin_Character_Classifier_00379,output,WBB,Baybayin_counter,app.Tag_Words,A,RW);
           app.Image_2.Visible='on'; app.Image2.Visible='off';
           app.Image_2.ImageSource=output2; app.Trans_Image=output2;
           pause(0.001)
           app.TextArea_7.Visible='on';
           % Displaying the Coherent Transliteration
            Process1=cell(size(single_lines3,1),length(output));
            
            z1=0;
            if nnz(output)==0
                Process1=single_lines3;
            else
                for am=1:length(RW)
                if output(am)==1
                    Process1(:,am)=RW(am);
                else
                    z1=z1+1;
                    Process1(:,am)=single_lines3(:,z1);
                end
                end
            end
            
            rowCells = mat2cell(Process1, ones(1, size(Process1,1)), size(Process1,2));
            Phrase1 = cellfun(@(row) strjoin(row, ' '), rowCells, 'UniformOutput', false);
 
           app.TextArea_7.Value=Phrase1;
           %-------------------------------------------------------
           app.Out_Message=Phrase1;
           app.SaveImageButton.Visible='on'; app.SaveTransliterationsButton.Visible='on';
           end
           
           app.TextArea_5.Value='Done! Tap ''Upload New Image'' if you want to try another one.';
           app.TextArea_8.Visible='on'; 
          zz1=0; 
          for acm = 1:length(transliterations)
            if length(transliterations{acm})>1
                zz1=zz1+1;
            end
          end  
          app.TextArea_6.Value=replace(app.Message1,'Proceeding to transliterations, please wait.','Counted Ambiguous Baybayin Words: %d');%output caption
          app.TextArea_6.Value{5}=sprintf(app.TextArea_6.Value{5},zz1);

          app.ClicktoUploadImageButton.Enable='on'; app.OCROptionsDropDown.Enable='on'; app.TransliterateButton.Enable='on'; app.UploadNewImageButton.Enable='on';
          app.SaveImageButton.Enable='on'; app.SaveTransliterationsButton.Enable='on'; app.Button_3.Enable='on'; app.Button_4.Enable='on'; app.Button_5.Enable='on';          
         catch
             message2=char('Oops! System error. Possible causes: 1) Was not able to read texts, 2) Input image is noncompliant to the system assumptions, or 3) Transliteration timeout.');
             app.TextArea_6.Value=message2; app.TextArea_6.FontColor=[1 0 0];
             app.TextArea_5.Value='Done! Please try again.';
             
          app.ClicktoUploadImageButton.Enable='on'; app.OCROptionsDropDown.Enable='on'; app.TransliterateButton.Enable='on'; app.UploadNewImageButton.Enable='on';
          app.SaveImageButton.Enable='on'; app.SaveTransliterationsButton.Enable='on'; app.Button_3.Enable='on'; app.Button_4.Enable='on'; app.Button_5.Enable='on';             
             error('Oops! System error');
         end
        end

        % Button pushed function: SaveImageButton
        function SaveImageButtonPushed(app, event)
           [file11,path1] = uiputfile({'*.png;*.jpg;*.jpeg','Save as (*.png,*.jpg,*.jpeg)';'*.png','Portable Network Graphic file (*.png)';'*.jpg','JPG file (*.jpg)';'*.jpeg','JPEG file (*.jpeg)';'*.*','All Files (*.*)'});
            if file11==0
               figure(app.MAIBaybayOCRUIFigure);                
               return;
            end
            anss1=cell(1,2);
            anss1{1}=path1;
            anss1{2}=file11;
            anss2=strjoin(anss1,'');
            imwrite(app.Trans_Image,anss2); drawnow; figure(app.MAIBaybayOCRUIFigure);
        end

        % Button pushed function: SaveTransliterationsButton
        function SaveTransliterationsButtonPushed(app, event)
            [file111,path11] = uiputfile({'*.txt','Save as (*.txt)';'*.txt','Text Documents (*.txt)'});
            if file111==0
               figure(app.MAIBaybayOCRUIFigure);                
               return; 
            end
            anss11=cell(1,2);
            anss11{1}=path11;
            anss11{2}=file111;
            anss22=strjoin(anss11,'');
            writecell(app.Out_Message,anss22); drawnow; figure(app.MAIBaybayOCRUIFigure);
        end

        % Callback function
        function ButtonPushed(app, event)
            app.ClicktoUploadImageButton.Visible='on'; app.ClicktoUploadImageButton.Enable='on';
            app.UploadNewImageButton.Visible='off'; app.OCROptionsDropDown.ItemsData=[1,2]; app.OCROptionsDropDown.Value = 1;
            app.TextArea_6.Value={''};  app.TextArea_6.Visible='on'; app.TextArea_6.Enable='on';
            %app.TextArea_8.Value={''};  
            app.TextArea_8.Visible='off'; %app.TextArea_8.Enable='on';
            app.TextArea_7.Value={''};  app.TextArea_7.Visible='on'; app.TextArea_7.Enable='on';
            app.TextArea_5.Value={''};  app.TextArea_5.Visible='off'; app.TextArea_5.Enable='off';
            app.Image2.Visible='on'; app.Image_2.Visible='off';
            app.InputImage=[];
        end

        % Callback function
        function Button_2Pushed(app, event)
            fig1=uifigure('WindoWState','maximized'); 
            imm=uiimage(fig1); 
            imm.ImageSource='GUI Guide.png'; pause(0.5); 
            imm.Position=fig1.Position;
        end

        % Button pushed function: Button_4
        function Button_4Pushed(app, event)
    fig1 = uifigure('WindowState','maximized' ,'Name','MAIBaybay-OCR');

    g = uigridlayout(fig1, [1 1]); % single cell grid

    imm = uiimage(g);
    imm.ImageSource = '1_MaBaybay GUI Guide.png';
    imm.Layout.Row = 1;
    imm.Layout.Column = 1;
%    imm.ScaleMethod='fill';      
        end

        % Button pushed function: Button_5
        function Button_5Pushed(app, event)
            %clf();
            app.ClicktoUploadImageButton.Visible='on'; app.ClicktoUploadImageButton.Enable='on';
            app.UploadNewImageButton.Visible='off'; app.OCROptionsDropDown.ItemsData=[1,2]; app.OCROptionsDropDown.Value = 1;
            app.TextArea_6.Value={''};  app.TextArea_6.Visible='on'; app.TextArea_6.Enable='on';
            %app.TextArea_8.Value={''};  
            app.TextArea_8.Visible='off'; %app.TextArea_8.Enable='on';
            app.TextArea_7.Value={''};  app.TextArea_7.Visible='on'; app.TextArea_7.Enable='on';
            app.TextArea_5.Value={''};  app.TextArea_5.Visible='off'; app.TextArea_5.Enable='off';
            app.Image2.Visible='on'; app.Image_2.Visible='off';
            app.InputImage=[]; app.SaveImageButton.Visible='off'; app.SaveTransliterationsButton.Visible='off';
        end

        % Button pushed function: Button_3
        function Button_3Pushed(app, event)
            fig2=uifigure('Resize','off','Name','MAIBaybay-OCR');
            im1=uiimage(fig2);
            im1.ImageSource='1_PAGBATI.png';
            im1.Position=[1 1 fig2.Position(3) fig2.Position(4)];
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.MAIBaybayOCRUIFigure = uifigure('Visible', 'off');
            app.MAIBaybayOCRUIFigure.Name = 'MAIBaybay-OCR';
            app.GridLayout = uigridlayout(app.MAIBaybayOCRUIFigure);
            app.Panel_3 = uipanel(app.GridLayout);
            app.TextArea_4 = uitextarea(app.Panel_3);
            app.TextArea_4.Enable = 'off';
            app.TextArea_4.Value = {'Option Note (Baybayin Only): '; 'This option treats every detected word as Baybayin texts.'};
            app.TextArea_3 = uitextarea(app.Panel_3);
            app.TextArea_3.Enable = 'off';
            app.TextArea_3.Value = {'Option Note (Baybayin & Latin): '; 'This option distinguishes first the Latin/Roman texts from the Baybayin scripts. '; 'May cause longer transliteration time.'};
            app.OCROptionsDropDownLabel = uilabel(app.Panel_3);
            app.OCROptionsDropDownLabel.Text = 'OCR Options:';
            app.OCROptionsDropDown = uidropdown(app.Panel_3);
            app.OCROptionsDropDown.Items = {'Baybayin only', 'Baybayin & Latin', ''};
            app.OCROptionsDropDown.ItemsData = {'', ''};
            app.OCROptionsDropDown.ValueChangedFcn = createCallbackFcn(app, @OCROptionsDropDownValueChanged, true);
            app.OCROptionsDropDown.Value = '';
            app.Panel = uipanel(app.GridLayout);
            app.TextArea_6 = uitextarea(app.Panel);
            app.TextArea_6.Editable = 'off';
            app.TextArea_8 = uitextarea(app.Panel);
            app.TextArea_8.Editable = 'off';
            app.TextArea_8.Visible = 'off';
            app.TextArea_8.Value = {'Done!'};
            app.DetectionSummaryLabel_2 = uilabel(app.Panel);
            app.DetectionSummaryLabel_2.Text = {'Detection Summary'; ''};
            app.Panel_2 = uipanel(app.GridLayout);
            app.Image = uiimage(app.Panel_2);
            app.ClicktoUploadImageButton = uibutton(app.Panel_2, 'push');
            app.ClicktoUploadImageButton.ButtonPushedFcn = createCallbackFcn(app, @ClicktoUploadImageButtonPushed, true);
            app.ClicktoUploadImageButton.Text = {'Click to Upload Image'; ''};
            app.UploadNewImageButton = uibutton(app.Panel_2, 'push');
            app.UploadNewImageButton.ButtonPushedFcn = createCallbackFcn(app, @UploadNewImageButtonPushed, true);
            app.UploadNewImageButton.Enable = 'off';
            app.UploadNewImageButton.Visible = 'off';
            app.UploadNewImageButton.Text = 'Upload New Image';
            app.INPUTTEXTIMAGELabel = uilabel(app.GridLayout);
            app.INPUTTEXTIMAGELabel.Text = {'1 INPUT TEXT IMAGE'; ''};
            app.SELECTOCROPTIONLabel = uilabel(app.GridLayout);
            app.SELECTOCROPTIONLabel.Text = {'2 SELECT OCR OPTION'; ''};
            app.Panel_4 = uipanel(app.GridLayout);
            app.TransliterateButton = uibutton(app.Panel_4, 'push');
            app.TransliterateButton.ButtonPushedFcn = createCallbackFcn(app, @TransliterateButtonPushed, true);
            app.TransliterateButton.Text = {'Transliterate'; ''};
            app.TextArea_5 = uitextarea(app.Panel_4);
            app.TextArea_5.Editable = 'off';
            app.TextArea_5.Enable = 'off';
            app.TextArea_5.Visible = 'off';
            app.CONVERTLabel_2 = uilabel(app.Panel_4);
            app.CONVERTLabel_2.Text = {'3 CONVERT'; ''};
            app.Panel_5 = uipanel(app.GridLayout);
            app.Image_2 = uiimage(app.Panel_5);
            app.Image2 = uiimage(app.Panel_5);
            app.Image2.Enable = 'off';
            app.Image2.Visible = 'off';
            app.SaveImageButton = uibutton(app.Panel_5, 'push');
            app.SaveImageButton.ButtonPushedFcn = createCallbackFcn(app, @SaveImageButtonPushed, true);
            app.SaveImageButton.Visible = 'off';
            app.SaveImageButton.Text = 'Save Image';
            app.ConvertedImageLabel = uilabel(app.Panel_5);
            app.ConvertedImageLabel.Text = 'Converted Image';
            app.Panel_6 = uipanel(app.GridLayout);
            app.TextArea_7 = uitextarea(app.Panel_6);
            app.TextArea_7.Editable = 'off';
            app.SaveTransliterationsButton = uibutton(app.Panel_6, 'push');
            app.SaveTransliterationsButton.ButtonPushedFcn = createCallbackFcn(app, @SaveTransliterationsButtonPushed, true);
            app.SaveTransliterationsButton.Visible = 'off';
            app.SaveTransliterationsButton.Text = 'Save Transliterations';
            app.TransliterationLabel = uilabel(app.Panel_6);
            app.TransliterationLabel.Text = 'Transliteration';
            app.TextArea_9 = uitextarea(app.GridLayout);
            app.Button_3 = uibutton(app.GridLayout, 'push');
            app.Button_3.ButtonPushedFcn = createCallbackFcn(app, @Button_3Pushed, true);
            app.Button_3.Text = '';
            app.Button_5 = uibutton(app.GridLayout, 'push');
            app.Button_5.ButtonPushedFcn = createCallbackFcn(app, @Button_5Pushed, true);
            app.Button_5.Icon = '1_Reset.png';
            app.Button_5.Text = '';
            app.Button_4 = uibutton(app.GridLayout, 'push');
            app.Button_4.ButtonPushedFcn = createCallbackFcn(app, @Button_4Pushed, true);
            app.Button_4.Icon = '1_Help.png';
            app.Button_4.Text = '';
            % Show the figure after all components are created
            app.MAIBaybayOCRUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MAIBayBayOCR_App

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MAIBaybayOCRUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MAIBaybayOCRUIFigure)
        end
    end
end